classdef SFEM_4DP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayoutOverview              matlab.ui.container.GridLayout
        SimulationSettingPanel          matlab.ui.container.Panel
        GridLayoutSetting               matlab.ui.container.GridLayout
        TimeUnitDropDown                matlab.ui.control.DropDown
        SizeScaleEditField              matlab.ui.control.NumericEditField
        SizeScaleEditFieldLabel         matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        TimeScaleEditField              matlab.ui.control.NumericEditField
        TimeScaleEditFieldLabel         matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        SimulationTypeDropDownLabel     matlab.ui.control.Label
        Mesh2DPanel                     matlab.ui.container.Panel
        GridLayoutMesh2D                matlab.ui.container.GridLayout
        degLabel                        matlab.ui.control.Label
        theta                           matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        zLabel2D                        matlab.ui.control.Label
        yLabel2D                        matlab.ui.control.Label
        xLabel2D                        matlab.ui.control.Label
        ShapeDropDown2D                 matlab.ui.control.DropDown
        zlength2D                       matlab.ui.control.NumericEditField
        mLabel_6                        matlab.ui.control.Label
        NumberofNodes2Dy                matlab.ui.control.NumericEditField
        NumberofNodesLabel_2            matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        ylength2D                       matlab.ui.control.NumericEditField
        NumberofNodes2Dx                matlab.ui.control.NumericEditField
        NumberofNodesLabel              matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        xlength2D                       matlab.ui.control.NumericEditField
        Mesh2DButton                    matlab.ui.control.Button
        ShapeDropDownLabel              matlab.ui.control.Label
        Mesh3DPanel                     matlab.ui.container.Panel
        GridLayoutMesh3D                matlab.ui.container.GridLayout
        Mesh3DButton                    matlab.ui.control.Button
        NumberofNodes3Dz                matlab.ui.control.NumericEditField
        NumberofNodesEditField_3Label   matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        zlength3D                       matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        NumberofNodes3Dy                matlab.ui.control.NumericEditField
        NumberofNodesEditField_2Label   matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        ylength3D                       matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        NumberofNodes3Dx                matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        xlength3D                       matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        ShapeDropDown3D                 matlab.ui.control.DropDown
        ShapeDropDown_2Label            matlab.ui.control.Label
        Constrain2DPanel                matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Apply2DButton                   matlab.ui.control.Button
        xyCon2DEditField                matlab.ui.control.EditField
        xyLabel                         matlab.ui.control.Label
        yCon2DEditField                 matlab.ui.control.EditField
        ydirectionLabel                 matlab.ui.control.Label
        xCon2DEditField                 matlab.ui.control.EditField
        xdirectionLabel                 matlab.ui.control.Label
        Constrain3DPanel                matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Apply3DButton                   matlab.ui.control.Button
        xyzCon3DEditField               matlab.ui.control.EditField
        xyzfixedEditFieldLabel          matlab.ui.control.Label
        yzCon3DEditField                matlab.ui.control.EditField
        yzfixedEditFieldLabel           matlab.ui.control.Label
        zCon3DEditField                 matlab.ui.control.EditField
        zfixedEditFieldLabel            matlab.ui.control.Label
        xzCon3DEditField                matlab.ui.control.EditField
        xzfixedEditFieldLabel           matlab.ui.control.Label
        yCon3DEditField                 matlab.ui.control.EditField
        yfixedEditFieldLabel            matlab.ui.control.Label
        xyCon3DEditField                matlab.ui.control.EditField
        xyfixedEditFieldLabel           matlab.ui.control.Label
        xCon3DEditField                 matlab.ui.control.EditField
        xfixedEditFieldLabel            matlab.ui.control.Label
        MaterialAssignmentPanel         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SubregionPanel                  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ElementIndicesLabel             matlab.ui.control.Label
        SetElementIndicesButton         matlab.ui.control.Button
        StrainXTextAreaLabel            matlab.ui.control.Label
        StrainYTextAreaLabel            matlab.ui.control.Label
        StrainZTextAreaLabel            matlab.ui.control.Label
        SetMsrZButton                   matlab.ui.control.Button
        SetMsrYButton                   matlab.ui.control.Button
        SetMsrXButton                   matlab.ui.control.Button
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        MeasuredStrainLabel             matlab.ui.control.Label
        SubregionApplyButton            matlab.ui.control.Button
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        ViscoCoefficientEditField       matlab.ui.control.NumericEditField
        ViscoCoefficientEditFieldLabel  matlab.ui.control.Label
        ColorLabel                      matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        SubregionDropDown               matlab.ui.control.DropDown
        SubregionDropDownLabel          matlab.ui.control.Label
        NumberofsubregionsEditField     matlab.ui.control.NumericEditField
        NumberofsubregionsEditFieldLabel  matlab.ui.control.Label
        PreviewImagePanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BoxOnCheckBox                   matlab.ui.control.CheckBox
        SaveAsButton                    matlab.ui.control.Button
        GridOnCheckBox                  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        VideoPanel                      matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        AngleOutputDropDown             matlab.ui.control.DropDown
        AngleOutputDropDownLabel        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        PathLabel                       matlab.ui.control.Label
        SavePathButton                  matlab.ui.control.Button
        BoxOnCheckBoxVideo              matlab.ui.control.CheckBox
        GridOnCheckBoxVideo             matlab.ui.control.CheckBox
        GenerateVideoButton             matlab.ui.control.Button
        UIAxesVideo                     matlab.ui.control.UIAxes
        GridLayoutTitle                 matlab.ui.container.GridLayout
        ExportSettingsButton            matlab.ui.control.Button
        ImportSettingsButton            matlab.ui.control.Button
        DPrintingLabel                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GravityCheckBox                 matlab.ui.control.CheckBox
        SaveDataButton                  matlab.ui.control.Button
        RunCostTextArea                 matlab.ui.control.TextArea
        RunCostTextAreaLabel            matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        SelectedFolderPath
        SimulationTime
        TimeScale
        SizeScale
        x_2D; numNode_x_2D;
        y_2D; numNode_y_2D;
        z_2D;
        theta_2D;

        x_3D; numNode_x_3D;
        y_3D; numNode_y_3D;
        z_3D; numNode_z_3D;
        object4D;
        
        % Define a state variable to ensure the correct execution sequence
        isMeshed2D logical = false;
        isMeshed3D logical = false;

        %constrain
        xCon2D; yCon2D; xyCon2D;
        xCon3D; yCon3D; zCon3D; xyCon3D; xzCon3D; yzCon3D; xyzCon3D;
        A;b0;b1;
        result_time;
        result_q;
       
        constraintMarkers
        SubregionProperties;
        SimulationProgressDlg    
        VideoProgressDlg
          
        newSimProgress;
        oldSimProgress;
        issetSubregion;
        
        breakSliderPoints;
        actualSliderValues; % array 
        VideoScale;
        
        isGravity;
        isAngleOutput;
    end
    
    methods (Access = private)
        function numbers = parseInput(~, inputText)
            if isempty(inputText)
                numbers = [];
            else
                if isempty(regexp(inputText, '^[\d\s,]*$', 'once'))
                    msgbox('The input contains invalid characters. Please enter only integers separated by commas or spaces.', 'Error', 'error');
                    numbers = [];
                    return;
                end
                numbers = str2num(inputText);  % Convert input to numeric array 
                if any(numbers ~= fix(numbers))  % Check if all values are integers
                    msgbox('The input contains non-integer values. Please enter valid integers.', 'Error', 'error');
                    numbers = [];
                    return;
                end
            end
        end
        
        % to simplify subregion element input
        function simplifiedStr = simplifyIndices(~,indicesVec)
            simplifiedStr = '';
            rangeStart = indicesVec(1);
            for i = 2:length(indicesVec)
                if indicesVec(i) ~= indicesVec(i-1) + 1
                    if rangeStart == indicesVec(i-1)
                        simplifiedStr = [simplifiedStr, num2str(rangeStart), ' ']; 
                    else
                        simplifiedStr = [simplifiedStr, num2str(rangeStart), ':', num2str(indicesVec(i-1)), ' '];
                    end
                    rangeStart = indicesVec(i);
                end
            end          
            % add the last
            if rangeStart == indicesVec(end)
                simplifiedStr = [simplifiedStr, num2str(rangeStart)]; 
            else
                simplifiedStr = [simplifiedStr, num2str(rangeStart), ':', num2str(indicesVec(end))]; 
            end   
            % delete final space 
            simplifiedStr = strtrim(simplifiedStr);
        end


        function actualValue = calculateActualValue(app, sliderPos)
            breakPoints = app.breakSliderPoints;
            actualValues = app.actualSliderValues;
            
            % confirm the current range
            if sliderPos <= breakPoints(1)
                actualValue = actualValues(1);
                return;
            elseif sliderPos >= breakPoints(end)
                actualValue = actualValues(end);
                return;
            end
            
            for i = 1:length(breakPoints)-1
                if sliderPos >= breakPoints(i) && sliderPos <= breakPoints(i+1)
                    % calculate the current postition ratio
                    ratio = (sliderPos - breakPoints(i)) / (breakPoints(i+1) - breakPoints(i));
                    % calculate the corresponding value
                    actualValue = actualValues(i) + ratio * (actualValues(i+1) - actualValues(i));                                      
                    actualValue = round(actualValue*10)/10;
                    return;
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Simulation setting panel
            app.TypeDropDown.Value = '2D';
            app.SizeScale = 10;               % simulation size : real size
            app.TimeScale = 1;                % real time: simulation time
            app.SimulationTime = 1;
 
            app.SimulationTimeEditField.Value = app.SimulationTime; % map data to app 
            app.SizeScaleEditField.Value = app.SizeScale;
            app.TimeScaleEditField.Value = app.TimeScale; 
            % 2. Mesh 2D
            app.x_2D = 0; app.numNode_x_2D = 0;
            app.y_2D = 0; app.numNode_y_2D = 0;
            app.z_2D = 0; app.theta_2D = 0;
            app.xlength2D.Value = app.x_2D; app.NumberofNodes2Dx.Value = app.numNode_x_2D;
            app.ylength2D.Value = app.y_2D; app.NumberofNodes2Dy.Value = app.numNode_y_2D;
            app.zlength2D.Value = app.z_2D;
            app.degLabel.Visible = "off";
            app.theta.Visible = "off";
            app.thetaLabel.Visible = "off";

            % 3. Mesh 3D
            app.Mesh3DPanel.Visible = "off";
            app.x_3D = 0; app.numNode_x_3D = 0;
            app.y_3D = 0; app.numNode_y_3D = 0;
            app.z_3D = 0; app.numNode_z_3D = 0;   
            app.xlength3D.Value = app.x_3D; app.NumberofNodes3Dx.Value = app.numNode_x_3D;
            app.ylength3D.Value = app.y_3D; app.NumberofNodes3Dy.Value = app.numNode_y_3D;
            app.zlength3D.Value = app.z_3D; app.NumberofNodes3Dz.Value = app.numNode_z_3D;
            % 4. Constrain 2D
            app.Constrain2DPanel.Enable = "off";
            app.xCon2DEditField.FontColor = 'r';
            app.yCon2DEditField.FontColor = 'g';
            app.xyCon2DEditField.FontColor = 'b';
            % 5. Constrain 3D
            app.Constrain3DPanel.Visible = 'off';
            % Royal Blue (深蓝色) (0.255, 0.412, 0.882)
            % Forest Green (森林绿)(0.133, 0.545, 0.133)
            % Goldenrod (金麒麟色) (0.855, 0.647, 0.125)
            % Crimson (深红色)      (0.863, 0.078, 0.235)
            % Slate Gray (板岩灰色) (0.439, 0.502, 0.565)
            % Dark Orange (深橙色)  (1.0, 0.549, 0.0)
            %  Medium Purple       (0.576, 0.439, 0.859)
            app.xCon3DEditField.FontColor = [0.255, 0.412, 0.882];
            app.yCon3DEditField.FontColor = [0.133, 0.545, 0.133];
            app.zCon3DEditField.FontColor = [0.855, 0.647, 0.125];
            app.xyCon3DEditField.FontColor = [0.576, 0.439, 0.859];
            app.xzCon3DEditField.FontColor = [0.439, 0.502, 0.565];
            app.yzCon3DEditField.FontColor = [1.0, 0.549, 0.0];
            app.xyzCon3DEditField.FontColor = [0.863, 0.078, 0.235];%[0.576, 0.439, 0.859];

            app.A =[];
            app.b0 =[];
            app.b1 =[];

            % 6. Material Assignment
            app.MaterialAssignmentPanel.Enable ="off";
            app.StrainZTextAreaLabel.Enable = "off";
            app.SetMsrZButton.Enable = "off";
         
            %7 Preview image
            %simulation
            app.newSimProgress = 0;
            app.oldSimProgress = [];
            %8 Video
            app.VideoPanel.Enable = "off";
            app.RunSimulationButton.Enable ="off";
            app.GenerateVideoButton.Enable = "off";

            app.SaveDataButton.Enable ="off";
            %app.SelectedFolderPath ='';
            %app.SavePathEditField.Value ="";
            app.SelectedFolderPath ='./output';
            app.SavePathEditField.Value ="./output";
          
            %slider label
            app.breakSliderPoints = [0, 1, 2, 3, 4, 5]; 
            app.actualSliderValues = [0.1, 1.0, 2.0, 5.0, 10.0, 100.0];     
            app.Slider.Limits = [0 5];
            app.Slider.Value = 1; 
            app.Slider.MajorTicks = [0, 1, 2, 3, 4, 5];
            app.Slider.MajorTickLabels = {'0.1', '1.0', '2.0', '5.0', '10.0', '100.0'};
            app.Slider.MinorTicks = []; 
            app.SliderLabel.Text = '$1.0 \times$';
            app.SliderLabel.Interpreter = 'latex';       
            app.VideoScale = 1.0;

            % about constrain
            app.constraintMarkers = gobjects(1);  % graphic objects 
            % subregion properties
            numSubregions = 1;
            app.SubregionProperties = repmat(struct(...
                'ElementIndices', '', ...          
                'ViscoCoefficient', 10, ...        
                'YoungsModulus', 0, ...            
                'PoissonsRatio', 0.45, ...        
                'Density', 1000, ...               
                'MeasuredStrainX', '', ...         % data type: text
                'MeasuredStrainY', '', ...         
                'MeasuredStrainZ', '', ...         
                'Color', [0, 0, 0] ... 
            ), numSubregions, 1);

            % UIAxes
            app.UIAxes.LineWidth = 1.5;
            xlabel(app.UIAxes,"$x$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);
            ylabel(app.UIAxes,"$y$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);
            zlabel(app.UIAxes,"$z$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);


            % UIAxesVideo
            app.UIAxesVideo.LineWidth = 1.5;
            xlabel(app.UIAxesVideo,"$x$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);
            ylabel(app.UIAxesVideo,"$y$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);
            zlabel(app.UIAxesVideo,"$z$ [m]",'Interpreter','latex','FontName','Times New Roman','FontSize',20);   

            app.isAngleOutput = 0;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;   
            if strcmp(value, '2D')
                app.Mesh2DPanel.Visible = 'on';
                app.Constrain2DPanel.Visible = "on";   
                app.Mesh3DPanel.Visible = 'off';
                app.Constrain3DPanel.Visible = 'off'; 
                if app.isMeshed2D
                   app.MaterialAssignmentPanel.Enable= 'on';
                   app.Constrain2DPanel.Enable = "on";
                   app.VideoPanel.Enable = "on";
                else
                   app.MaterialAssignmentPanel.Enable= 'off';
                   app.Constrain2DPanel.Enable = "off";
                   app.VideoPanel.Enable = "off";
                end              
                app.StrainZTextAreaLabel.Enable =  'off'; 
                app.SetMsrZButton.Enable = "off";
            elseif strcmp(value, '3D')
                app.Mesh2DPanel.Visible = 'off';
                app.Constrain2DPanel.Visible = "off";
                app.Mesh3DPanel.Visible = 'on';
                app.Constrain3DPanel.Visible = 'on';               
                if app.isMeshed3D
                   app.MaterialAssignmentPanel.Enable= 'on';
                   app.Constrain3DPanel.Enable = "on";
                   app.VideoPanel.Enable = "on";
                else
                   app.MaterialAssignmentPanel.Enable= 'off';
                   app.Constrain3DPanel.Enable = "off";
                   app.VideoPanel.Enable = "off";
                end
                app.StrainZTextAreaLabel.Enable =  'on'; 
                app.SetMsrZButton.Enable = "on";
            end          
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            value = app.SimulationTimeEditField.Value;
            app.SimulationTime = value;
        end

        % Value changed function: TimeScaleEditField
        function TimeScaleEditFieldValueChanged(app, event)
            value = app.TimeScaleEditField.Value;
             app.TimeScale = value;
        end

        % Value changed function: SizeScaleEditField
        function SizeScaleEditFieldValueChanged(app, event)
            value = app.SizeScaleEditField.Value;
            app.SizeScale = value;
        end

        % Value changed function: ShapeDropDown2D
        function ShapeDropDown2DValueChanged(app, event)
            value = app.ShapeDropDown2D.Value;
            switch(value)
                case "Rectangular"
                    app.xLabel2D.Text = "x";
                    app.yLabel2D.Text = "y";
                    app.degLabel.Visible = "off";
                    app.theta.Visible = "off";
                    app.thetaLabel.Visible = "off";
                case "Doughnut"
                    app.xLabel2D.Text = "Outer R";
                    app.yLabel2D.Text = "Inner R";
                    app.degLabel.Visible = "off";
                    app.theta.Visible = "off";
                    app.thetaLabel.Visible = "off";
                case "Half Doughnut"
                    app.xLabel2D.Text = "Outer R";
                    app.yLabel2D.Text = "Inner R";
                    app.degLabel.Visible = "on";
                    app.theta.Visible = "on";
                    app.thetaLabel.Visible = "on";
            end
        end

        % Value changed function: xlength2D
        function xlength2DValueChanged(app, event)
            value = app.xlength2D.Value;
            app.x_2D = value;
        end

        % Value changed function: ylength2D
        function ylength2DValueChanged(app, event)
            value = app.ylength2D.Value;
            app.y_2D = value;
        end

        % Value changed function: zlength2D
        function zlength2DValueChanged(app, event)
            value = app.zlength2D.Value;
            app.z_2D = value;
        end

        % Value changed function: theta
        function thetaValueChanged(app, event)
            value = app.theta.Value;
            app.theta_2D = value;
        end

        % Value changed function: NumberofNodes2Dx
        function NumberofNodes2DxValueChanged(app, event)
            value = app.NumberofNodes2Dx.Value;
            app.numNode_x_2D = value;
        end

        % Value changed function: NumberofNodes2Dy
        function NumberofNodes2DyValueChanged(app, event)
            value = app.NumberofNodes2Dy.Value;
            app.numNode_y_2D = value;
        end

        % Value changed function: xlength3D
        function xlength3DValueChanged(app, event)
            value = app.xlength3D.Value;
            app.x_3D = value;
        end

        % Value changed function: ylength3D
        function ylength3DValueChanged(app, event)
            value = app.ylength3D.Value;
            app.y_3D = value;
        end

        % Value changed function: zlength3D
        function zlength3DValueChanged(app, event)
            value = app.zlength3D.Value;
            app.z_3D = value;
        end

        % Value changed function: NumberofNodes3Dx
        function NumberofNodes3DxValueChanged(app, event)
            value = app.NumberofNodes3Dx.Value;
            app.numNode_x_3D = value;
        end

        % Value changed function: NumberofNodes3Dy
        function NumberofNodes3DyValueChanged(app, event)
            value = app.NumberofNodes3Dy.Value;
            app.numNode_y_3D = value;
        end

        % Value changed function: NumberofNodes3Dz
        function NumberofNodes3DzValueChanged(app, event)
            value = app.NumberofNodes3Dz.Value;
            app.numNode_z_3D = value;
        end

        % Button pushed function: Mesh2DButton
        function Mesh2DButtonPushed(app, event)
            epsilon = 1e-9;  % Set a very small tolerance.
            try
                if abs(app.SizeScale) < epsilon
                    error('Size scale must be a positive real number.');
                end
                if abs(app.x_2D) < epsilon
                    error('Size must be positive.');
                end
                if abs(app.y_2D) < epsilon
                    error('Size must be positive.');
                end
                if abs(app.z_2D) < epsilon
                    error('Size must be positive.');
                end
                if abs(app.numNode_x_2D) < 1 + epsilon || abs(app.numNode_y_2D) < 1 + epsilon
                    error('Number of nodes must be an integer greater than one.');
                end   
            catch ME   
                errordlg(ME.message, 'Error', 'modal');
                return;
            end
             app.object4D =[];
             app.object4D = meshObject(app);
             app.isMeshed2D = true;
             app.MaterialAssignmentPanel.Enable ="on";
             app.Constrain2DPanel.Enable = "on";
             app.VideoPanel.Enable = "on";
        end

        % Button pushed function: Mesh3DButton
        function Mesh3DButtonPushed(app, event)
            epsilon = 1e-9;  % Set a very small tolerance.
            try
                if abs(app.SizeScale) < epsilon
                    error('Size scale must be a positive real number.');
                end
                if abs(app.x_3D) < epsilon
                    error('x must be a positive real number.');
                end
                if abs(app.y_3D) < epsilon
                    error('y must be a positive real number.');
                end
                if abs(app.z_3D) < epsilon
                    error('z must be a positive real number.');
                end
                if abs(app.numNode_x_3D) < 1 + epsilon || abs(app.numNode_y_3D) < 1 + epsilon || abs(app.numNode_z_3D) < 1 + epsilon
                    error('Number of nodes must be an integer greater than one.');
                end   
            catch ME   
                errordlg(ME.message, 'Error', 'modal');
                return;
            end
             app.object4D =[];
             app.object4D = meshObject(app);
             app.isMeshed3D = true;
             app.MaterialAssignmentPanel.Enable ="on";
             app.Constrain3DPanel.Enable = "on";
             app.VideoPanel.Enable = "on";
        end

        % Button pushed function: Apply2DButton
        function Apply2DButtonPushed(app, event)
           
            % Get user input from the three Edit Fields
            xConPointsText = app.xCon2DEditField.Value;
            yConPointsText = app.yCon2DEditField.Value;
            xyConPointsText = app.xyCon2DEditField.Value;
            
            % Parse the input text, convert strings to integer arrays, return empty if input is empty
            app.xCon2D = parseInput(app,xConPointsText);
            app.yCon2D = parseInput(app,yConPointsText);
            app.xyCon2D = parseInput(app,xyConPointsText);
            
            allPoints = [app.xCon2D, app.yCon2D, app.xyCon2D];
             if isempty(allPoints)
                  app.UIAxes.Title.String = "No constraints";
             else
                 app.UIAxes.Title.String = "";
             end

             try
                if length(allPoints) ~= length(unique(allPoints))
                    error('Please ensure that different constraints do not contain the same values.');
                end
                if  any(allPoints > app.object4D.numNodalPoints)|| any (allPoints < 1)
                    msg = sprintf('Constraint inputs must be between 1 and %d.', app.object4D.numNodalPoints);
                    error('Error: %s', msg);
                end
            catch ME   
                errordlg(ME.message, 'Error', 'modal');
                return;
             end
            % delete all old markers
            if ~isempty(app.constraintMarkers)
                for i = 1:length(app.constraintMarkers)
                    if isgraphics(app.constraintMarkers(i))
                        delete(app.constraintMarkers(i)); 
                    end
                end
                app.constraintMarkers = gobjects(1, 0);
            end             
            [app.A, app.b0, app.b1, app.constraintMarkers]= applyConstrain(app);

        end

        % Button pushed function: Apply3DButton
        function Apply3DButtonPushed(app, event)
            % Get user input from the three Edit Fields
            xConPointsText = app.xCon3DEditField.Value;
            yConPointsText = app.yCon3DEditField.Value;
            zConPointsText = app.zCon3DEditField.Value;
            xyConPointsText = app.xyCon3DEditField.Value;
            xzConPointsText = app.xzCon3DEditField.Value;
            yzConPointsText = app.yzCon3DEditField.Value;
            xyzConPointsText = app.xyzCon3DEditField.Value;
            % Parse the input text, convert strings to integer arrays, return empty if input is empty
            app.xCon3D = parseInput(app,xConPointsText);
            app.yCon3D = parseInput(app,yConPointsText);
            app.zCon3D = parseInput(app,zConPointsText);
            app.xyCon3D = parseInput(app,xyConPointsText);
            app.xzCon3D = parseInput(app,xzConPointsText);
            app.yzCon3D = parseInput(app,yzConPointsText);
            app.xyzCon3D = parseInput(app,xyzConPointsText);
            
            allPoints = [app.xCon3D, app.yCon3D, app.zCon3D,app.xyCon3D,app.xzCon3D,app.yzCon3D,app.xyzCon3D];
             if isempty(allPoints)
                  app.UIAxes.Title.String = "No constraints";
             else
                 app.UIAxes.Title.String = "";
             end
             
             try
                if length(allPoints) ~= length(unique(allPoints))
                    error('Please ensure that different constraints do not contain the same values.');
                end
                if  any(allPoints > app.object4D.numNodalPoints)|| any (allPoints < 1)
                    msg = sprintf('Constraint inputs must be between 1 and %d.', app.object4D.numNodalPoints);
                    error('Error: %s', msg);
                end
            catch ME   
                errordlg(ME.message, 'Error', 'modal');
                return;
             end
            % delete all old markers
            if ~isempty(app.constraintMarkers)
                for i = 1:length(app.constraintMarkers)
                    if isgraphics(app.constraintMarkers(i))
                        delete(app.constraintMarkers(i));  % 删除每一个 marker
                    end
                end
                app.constraintMarkers = gobjects(1, 0);
            end             
            [app.A, app.b0, app.b1, app.constraintMarkers]= applyConstrain(app); 
        end

        % Value changed function: NumberofsubregionsEditField
        function NumberofsubregionsEditFieldValueChanged(app, event)
            % get the numSubregions value from user input
            newNumSubregions = app.NumberofsubregionsEditField.Value;     
             % current number of subregions
            currentNumSubregions = length(app.SubregionProperties); 
            if newNumSubregions > currentNumSubregions
                % add subregion
                for i = (currentNumSubregions + 1):newNumSubregions
                    %Initialize a new Subregion using the existing struct template
                    app.SubregionProperties(i) = app.SubregionProperties(1);
                    %Initialize properties for the newly added Subregion
                    app.SubregionProperties(i).ElementIndices = '';
                    app.SubregionProperties(i).ViscoCoefficient = 10;
                    app.SubregionProperties(i).YoungsModulus = 0;
                    app.SubregionProperties(i).PoissonsRatio = 0.45;
                    app.SubregionProperties(i).Density = 1000;
                    app.SubregionProperties(i).MeasuredStrainX = '';
                    app.SubregionProperties(i).MeasuredStrainY = '';
                    app.SubregionProperties(i).MeasuredStrainZ = '';
                    app.SubregionProperties(i).Color = [0, 0, 0];
                end
            elseif newNumSubregions < currentNumSubregions
                app.SubregionProperties(newNumSubregions+1:end) = [];
            end          
            % Generate corresponding dropdown options
            options = arrayfun(@num2str, 1:newNumSubregions, 'UniformOutput', false);          
            % update the option of dropdown
            app.SubregionDropDown.Items = options;
            % % Update the UI to display information for the first Subregion
            app.SubregionDropDown.Value = options{1};
            SubregionDropDownValueChanged(app, event); % % Call the update function to refresh the interface
        end

        % Value changed function: SubregionDropDown
        function SubregionDropDownValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            app.SubregionPanel.Title = "Subregion " + app.SubregionDropDown.Value;
            % Update EditField
            app.YoungsModulusEditField.Value = app.SubregionProperties(subregionIndex).YoungsModulus;
            app.DensityEditField.Value = app.SubregionProperties(subregionIndex).Density;
            app.ViscoCoefficientEditField.Value = app.SubregionProperties(subregionIndex).ViscoCoefficient;
            app.PoissonsRatioEditField.Value = app.SubregionProperties(subregionIndex).PoissonsRatio;
            % Update Color
            app.REditField.Value = app.SubregionProperties(subregionIndex).Color(1);
            app.GEditField.Value = app.SubregionProperties(subregionIndex).Color(2);
            app.BEditField.Value = app.SubregionProperties(subregionIndex).Color(3);
            % element indices
            if ~isempty(app.SubregionProperties(subregionIndex).ElementIndices)
               app.ElementIndicesLabel.Text = app.SubregionProperties(subregionIndex).ElementIndices;
            else
                app.ElementIndicesLabel.Text = "Element Indices";
            end
            % measured strain x
            if ~isempty(app.SubregionProperties(subregionIndex).MeasuredStrainX)
                app.StrainXTextAreaLabel.Text = app.SubregionProperties(subregionIndex).MeasuredStrainX;
                
            else
                app.StrainXTextAreaLabel.Text = '$u^{msr}_x(t)$';
                
            end
            % measured strain y
            if ~isempty(app.SubregionProperties(subregionIndex).MeasuredStrainY)
                app.StrainYTextAreaLabel.Text = app.SubregionProperties(subregionIndex).MeasuredStrainY;
                
            else
                app.StrainYTextAreaLabel.Text = '$v^{msr}_y(t)$'; 
                
            end
            % measured strain z
            if ~isempty(app.SubregionProperties(subregionIndex).MeasuredStrainZ)
                app.StrainZTextAreaLabel.Text = app.SubregionProperties(subregionIndex).MeasuredStrainZ;    
            else
                app.StrainZTextAreaLabel.Text = '$w^{msr}_z(t)$';        
            end
        end

        % Button pushed function: SetElementIndicesButton
        function SetElementIndicesButtonPushed(app, event)
            prompt = {'Enter element indices (e.g., 1:4 10):'};
            title = 'Input';
            dims = [1 50];
            definput = {'1:4 10'};
            answer = inputdlg(prompt, title, dims, definput);            
            
            if ~isempty(answer)
                % Get the element index string entered by the user
                indicesStr = answer{1};
                
                try
                    % change the string to number
                    indicesVec = str2num(indicesStr); 
                    % check
                    if isempty(indicesVec) || any(indicesVec <= 0) || any(mod(indicesVec,1) ~= 0)
                        error('Invalid input');
                    end    
                     typeValue = app.TypeDropDown.Value;   
                     if strcmp(typeValue, '2D')
                          if any(indicesVec > app.object4D.numTriangles)
                             error('Input values cannot be greater than %d.',app.object4D.numTriangles);
                          end
                     elseif strcmp(typeValue, '3D')
                          if any(indicesVec > app.object4D.numTetrahedrons)
                             error('Input values cannot be greater than %d.',app.object4D.numTetrahedrons);
                          end
                     end   
 
                    % delete repeat term
                    indicesVec = unique(sort(indicesVec));
                    
                    % simplify 
                    simplifiedStr = simplifyIndices(app, indicesVec);
                    
                    % show in label
                    app.ElementIndicesLabel.Text = simplifiedStr;
                    
                    % stored in SubregionProperties
                    subregionIndex = str2double(app.SubregionDropDown.Value);
                    app.SubregionProperties(subregionIndex).ElementIndices = simplifiedStr;
                    
                catch ME   
                    errordlg(ME.message, 'Error', 'modal');
                    return;
                end
            end
        end

        % Button pushed function: SetMsrXButton
        function SetMsrXButtonPushed(app, event)
            % dialogue
            prompt = {'Enter MATLAB expression for measured strain in x direction:'};
            title = 'Input';
            dims = [1 50];

            subregionIndex = str2double(app.SubregionDropDown.Value);
            if strlength(app.SubregionProperties(subregionIndex).MeasuredStrainX)==0
               definput = {'0.5*(1-exp(-0.5*t))'};
            else
                definput = {app.SubregionProperties(subregionIndex).MeasuredStrainX};
            end
       
            answer = inputdlg(prompt, title, dims, definput);            
            if ~isempty(answer)
                % get the input matlab expression
                matlabExpr = answer{1};                
                try
                    % Attempt to convert the string to a symbolic expression to check the syntax
                    tFunction = str2func(['@(t) ' matlabExpr]);           
                    tTestValue = 1; 
                    testValue = tFunction(tTestValue);
                    
                    app.SubregionProperties(subregionIndex).MeasuredStrainX = matlabExpr;         
                    app.StrainXTextAreaLabel.Text = matlabExpr;                                     
                catch ME   
                    errordlg(ME.message, 'Error', 'modal');
                    return;
                end
            end
        end

        % Button pushed function: SetMsrYButton
        function SetMsrYButtonPushed(app, event)
            prompt = {'Enter MATLAB expression for measured strain in y direction:'};
            title = 'Input';
            dims = [1 50];
           
            subregionIndex = str2double(app.SubregionDropDown.Value);
            if strlength(app.SubregionProperties(subregionIndex).MeasuredStrainY) ==0
                definput = {'0.5*(1-exp(-0.5*t))'};
            else
                definput = {app.SubregionProperties(subregionIndex).MeasuredStrainY};
            end

            answer = inputdlg(prompt, title, dims, definput);            
            
            if ~isempty(answer)
                matlabExpr = answer{1};
                try
                    tFunction = str2func(['@(t) ' matlabExpr]);           
                    tTestValue = 1; 
                    testValue = tFunction(tTestValue);
                    
                    app.SubregionProperties(subregionIndex).MeasuredStrainY = matlabExpr;         
                    app.StrainYTextAreaLabel.Text = matlabExpr;                  
                catch ME   
                    errordlg(ME.message, 'Error', 'modal');
                    return;
                end
            end
        end

        % Button pushed function: SetMsrZButton
        function SetMsrZButtonPushed(app, event)
            prompt = {'Enter MATLAB expression for measured strain in z direction:'};
            title = 'Input';
            dims = [1 50];
            subregionIndex = str2double(app.SubregionDropDown.Value);
            if strlength(app.SubregionProperties(subregionIndex).MeasuredStrainZ)==0
                 definput = {'0.5*(1-exp(0.5*t))'};
            else
                 definput ={app.SubregionProperties(subregionIndex).MeasuredStrainZ};
            end
            

            answer = inputdlg(prompt, title, dims, definput);            
            
            if ~isempty(answer)
               
                matlabExpr = answer{1};
                
                try
                    tFunction = str2func(['@(t) ' matlabExpr]);           
                    tTestValue = 1; 
                    testValue = tFunction(tTestValue);
                    
                    app.SubregionProperties(subregionIndex).MeasuredStrainZ = matlabExpr;         
  
                    app.StrainZTextAreaLabel.Text = matlabExpr;                    
                catch ME   
                    errordlg(ME.message, 'Error', 'modal');
                    return;
                end
            end
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            newRValue = app.REditField.Value;
            app.SubregionProperties(subregionIndex).Color(1) = newRValue;
        end

        % Value changed function: GEditField
        function GEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            newGValue = app.GEditField.Value;
            app.SubregionProperties(subregionIndex).Color(2) = newGValue;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            newBValue = app.BEditField.Value;
            app.SubregionProperties(subregionIndex).Color(3) = newBValue;
        end

        % Value changed function: ViscoCoefficientEditField
        function ViscoCoefficientEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            value = app.ViscoCoefficientEditField.Value;
            app.SubregionProperties(subregionIndex).ViscoCoefficient = value;
        end

        % Value changed function: YoungsModulusEditField
        function YoungsModulusEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            value = app.YoungsModulusEditField.Value;
            app.SubregionProperties(subregionIndex).YoungsModulus = value;
        end

        % Value changed function: PoissonsRatioEditField
        function PoissonsRatioEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            value = app.PoissonsRatioEditField.Value;
            app.SubregionProperties(subregionIndex).PoissonsRatio = value;
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)
            subregionIndex = str2double(app.SubregionDropDown.Value);
            value = app.DensityEditField.Value;
            app.SubregionProperties(subregionIndex).Density = value;
        end

        % Value changed function: GridOnCheckBox
        function GridOnCheckBoxValueChanged(app, event)
            value = app.GridOnCheckBox.Value;
            if value 
               app.UIAxes.XGrid = 'on';
               app.UIAxes.YGrid = 'on';
            else
               app.UIAxes.XGrid = 'off';
               app.UIAxes.YGrid = 'off';
            end
        end

        % Value changed function: BoxOnCheckBox
        function BoxOnCheckBoxValueChanged(app, event)
            value = app.BoxOnCheckBox.Value;
            if value 
               app.UIAxes.Box = 'on';    
            else
               app.UIAxes.Box ="off";
            end
        end

        % Value changed function: GridOnCheckBoxVideo
        function GridOnCheckBoxVideoValueChanged(app, event)
            value = app.GridOnCheckBoxVideo.Value;
             if value 
               app.UIAxesVideo.XGrid = 'on';
               app.UIAxesVideo.YGrid = 'on';
            else
               app.UIAxesVideo.XGrid = 'off';
               app.UIAxesVideo.YGrid = 'off';
            end
        end

        % Value changed function: BoxOnCheckBoxVideo
        function BoxOnCheckBoxVideoValueChanged(app, event)
            value = app.BoxOnCheckBoxVideo.Value;
            if value 
               app.UIAxesVideo.Box = 'on';    
            else
               app.UIAxesVideo.Box ="off";
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
             % choose figure save path 
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'; '*.bmp'; '*.pdf';'*.svg';'*.eps'}, 'Save Image As');
            
            if ischar(file)
                filename = fullfile(path, file);
                exportgraphics(app.UIAxes, filename, 'Resolution', 300);
                uialert(app.UIFigure, ['Image saved to: ' filename], 'Save As','Modal',true);
            end
        end

        % Button pushed function: SubregionApplyButton
        function SubregionApplyButtonPushed(app, event)
                app.issetSubregion = false;
                %  Element Indices
                allIndices = [];
                % iterate all subregions
                for i = 1:numel(app.SubregionProperties)
                    
                    currentIndicesStr = app.SubregionProperties(i).ElementIndices;
                    % Check if the current Element Indices is set to the default "Element Indices"
                    if strcmp(currentIndicesStr, 'Element Indices')
                        errordlg(['Subregion ' num2str(i) ' has not been assigned any triangular regions.'], 'Error', 'modal');
                        return;
                    end
                
                    % change Element Indices to num array
                    currentIndices = str2num(currentIndicesStr); 
                    
                    % check if it works
                    if isempty(currentIndices)
                        errordlg(['Invalid input in Element Indices for subregion ' num2str(i) '. ...' ...
                            ' Please enter a valid range of indices.'], 'Error', 'modal');
                        return;
                    end                  
                    % not over max
                     typevalue = app.TypeDropDown.Value;
                     if strcmp(typevalue, "2D")
                         max = app.object4D.numTriangles;
                     elseif strcmp(typevalue, "3D")
                         max = app.object4D.numTetrahedrons;
                     end
                
                    if any(currentIndices > max)
                        errordlg(['Element indices for subregion ' num2str(i) ' exceed the maximum allowed value of ' num2str(max) '.'], 'Error', 'modal');
                        return;
                    end
                    
                    allIndices = [allIndices, currentIndices];
                end
                
                % check repeat term
                [uniqueIndices, ~, idx] = unique(allIndices);
                counts = histcounts(idx, 0.5:1:(numel(uniqueIndices)+0.5));
                duplicateIndices = uniqueIndices(counts > 1);
                
                if ~isempty(duplicateIndices)
                    duplicateIndicesStr = sprintf('%d ', duplicateIndices);
                    errordlg(['There are duplicate regions assigned to different subregions: ' duplicateIndicesStr], 'Error', 'modal');
                    return;
                end
                % when pushed
                setSubregion(app);
                if app.issetSubregion
                    app.RunSimulationButton.Enable ="on";
                end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
                % Initialize an empty array to store the Element Indices for all subregions
               allIndices = [];
                % Iterate through all subregions to collect Element Indices
                for i = 1:numel(app.SubregionProperties)
                    currentIndicesStr = app.SubregionProperties(i).ElementIndices;   
                    currentIndices = str2num(currentIndicesStr);
                    allIndices = [allIndices, currentIndices]; 
                end     
               % Deduplicate and sort the allIndices array
                allIndices = unique(sort(allIndices));     
                 
                typeValue = app.TypeDropDown.Value;   
                if strcmp(typeValue, '2D')
                    n_element = app.object4D.numTriangles;
                elseif strcmp(typeValue, '3D')
                    n_element = app.object4D.numTetrahedrons;
                end
                targetIndices = 1:n_element;
                % Check if allIndices matches the target array
                if isequal(allIndices, targetIndices)  % % If they match, continue running the simulation
                   % create progress dialogue
                   app.SimulationProgressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Simulation', ...
                                                'Message', 'Calculating...', ...
                                                'Cancelable', 'on');

                    tic
                    [app.result_time, app.result_q]= runSimulation(app); 
                    % get the simulation time
                    elapsedTime = toc;
                    
                    elapsedTimeStr = sprintf(' %.2f s', elapsedTime);          
                    % display
                    app.RunCostTextArea.Value = elapsedTimeStr;
                    % done
                    close(app.SimulationProgressDlg);                     
                     % if not empty then can generate video
                    if ~isempty(app.result_q)
                        app.GenerateVideoButton.Enable = "on";
                        app.SaveDataButton.Enable = "on";
                    end
                else
                    % if not match then error
                    errordlg(['The Element Indices do not match the required range 1:' num2str(n_element) '.'], 'Error');
                    return;
                end

               app.newSimProgress = 0;
               app.oldSimProgress = [];
               app.RunSimulationButton.Enable ="off";
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
             folderPath = uigetdir;
            if folderPath ~= 0
                app.SavePathEditField.Value = folderPath;          
                msgbox(['Path confirmed: ', folderPath], 'Path Confirmed');             
                app.SelectedFolderPath = folderPath;
            end
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
                folderPath = app.SavePathEditField.Value;                            
                if startsWith(folderPath, './')
                    % relative path to absolute path
                    folderPath = fullfile(pwd, folderPath(3:end));
                end
                if any(ismember(folderPath, '*?"<>|'))
                    msgbox('The folder path contains invalid characters.', 'Error', 'error','modal');
                    app.SelectedFolderPath = "";
                    return;
                end           
                parentDir = fileparts(folderPath);               
                if isempty(parentDir) || ~exist(parentDir, 'dir')  
                    msgbox('The path is invalid', 'Error', 'error','modal');
                  app.SelectedFolderPath = "";
                    return;
                end              
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                    msgbox(['Folder created at: ', folderPath], 'Folder Created','modal');
                else
                    %msgbox(['Folder already exists at: ', folderPath], 'Folder Exists','modal');
                end
                app.SelectedFolderPath = folderPath;          
        end

        % Button pushed function: GenerateVideoButton
        function GenerateVideoButtonPushed(app, event)
               
                if strlength(app.SelectedFolderPath)==0     
                    msgbox('No valid path selected. The video will not be saved.', 'Warning', 'warn','modal');
                end
               app.UIFigure.Resize ="off";
               app.UIFigure.AutoResizeChildren ="off";
               
               app.VideoProgressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Video', ...
                                'Message', 'Video generating...', ...
                                'Cancelable', 'on');
             
               cla(app.UIAxesVideo);
               generateVideo(app);
               close(app.VideoProgressDlg);  
               app.UIFigure.Resize ="on";
               app.UIFigure.AutoResizeChildren ="on";
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [file, path] = uiputfile('*.mat', 'Save Simulation Data As');
                       
            if isequal(file, 0)
                return;
            end            
            
            fullFilePath = fullfile(path, file);            
            % data to save
            data_object = app.object4D;        
            data_q = app.result_q;        
            data_time = app.result_time;  
            
            %  .mat file
            save(fullFilePath, 'data_object', 'data_q', 'data_time',"-v7.3","-nocompression");    
            msgbox('Simulation data saved successfully.', 'Success');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % changingValue = event.Value;
            sliderPos = event.Value;
            actualValue = calculateActualValue(app, sliderPos);       
            app.VideoScale = actualValue;
            % 更新 Label 显示
            app.SliderLabel.Text = sprintf('$%.1f \\times$', actualValue);
        end

        % Value changed function: GravityCheckBox
        function GravityCheckBoxValueChanged(app, event)
            value = app.GravityCheckBox.Value;
            app.isGravity = value;
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            settings = struct();
            % 1.  SimulationSettingPanel
            settings.timeUnit = app.TimeUnitDropDown.Value;
            settings.sizeScale  = app.SizeScaleEditField.Value;
            settings.simulationTime = app.SimulationTimeEditField.Value;
            settings.timeScale = app.TimeScaleEditField.Value;
            settings.type = app.TypeDropDown.Value;

            %2.1  Mesh2DPanel
            settings.isMesh2DPanel = app.Mesh2DPanel.Visible;
            settings.shape2D  = app.ShapeDropDown2D.Value;
            settings.xlength2D = app.xlength2D.Value;
            settings.ylength2D = app.ylength2D.Value;
            settings.zlength2D = app.zlength2D.Value;
            settings.NumberofNodes2Dx = app.NumberofNodes2Dx.Value;
            settings.NumberofNodes2Dy = app.NumberofNodes2Dy.Value;
            %2.2 Mesh3DPanel
            settings.isMesh3DPanel = app.Mesh3DPanel.Visible;
            settings.shape3D = app.ShapeDropDown3D.Value;
            settings.xlength3D = app.xlength3D.Value;
            settings.ylength3D = app.ylength3D.Value;
            settings.zlength3D = app.zlength3D.Value;
            settings.NumberofNodes3Dx = app.NumberofNodes3Dx.Value;
            settings.NumberofNodes3Dy = app.NumberofNodes3Dy.Value;
            settings.NumberofNodes3Dz = app.NumberofNodes3Dz.Value;

            %3.1 Constrain2DPanel
            settings.isConstrain2DPanel = app.Constrain2DPanel.Visible;
            settings.xyCon2D = app.xyCon2DEditField.Value;
            settings.xCon2D = app.xCon2DEditField.Value;
            settings.yCon2D = app.yCon2DEditField.Value;
            %3.2 Constrain3DPanel
            settings.isConstrain3DPanel = app.Constrain3DPanel.Visible;           
            settings.xCon3D = app.xCon3DEditField.Value;
            settings.yCon3D = app.yCon3DEditField.Value;
            settings.zCon3D = app.zCon3DEditField.Value;
            settings.xyCon3D = app.xyCon3DEditField.Value;
            settings.xzCon3D = app.xzCon3DEditField.Value;
            settings.yzCon3D = app.yzCon3DEditField.Value;
            settings.xyzCon3D = app.xyzCon3DEditField.Value;

            %4 MaterialAssignmentPanel
            settings.isMaterialAssignmentPanel = app.MaterialAssignmentPanel.Visible;
            settings.numSubregion = app.NumberofsubregionsEditField.Value;
            settings.subregionProperties = app.SubregionProperties;
            settings.videoSaveFolder = app.SavePathEditField.Value;
            

             % Open the file save dialog
            [file, path] = uiputfile('*.mat', 'Save Settings As');
            
            % Check if the user did not cancel the dialog
            if isequal(file, 0) || isequal(path, 0)
                % User cancelled the file save operation
                disp('User cancelled the file save operation.');
            else
                % Full path to the save file
                filename = fullfile(path, file);
                
                 save(filename, 'settings');
                % Notify the user that the settings were saved successfully
                uialert(app.UIFigure, 'Settings saved successfully.', 'Success','Modal',true);
            end
            
            
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
          try 
                % Open the file open dialog
                [file, path] = uigetfile('*.mat', 'Import Settings');    
                % Check if the user did not cancel the dialog
                if isequal(file, 0) || isequal(path, 0)
                    % User cancelled the file load operation
                    disp('User cancelled the file load operation.');
                else
                    % Full path to the file
                    filename = fullfile(path, file);
                    
                    % Load the settings variable from the .mat file
                    loadedData = load(filename, 'settings');
                    settings = loadedData.settings;
                    
                    % Set the app components according to the settings variable
                    % 1. SimulationSettingPanel
                    app.TimeUnitDropDown.Value = settings.timeUnit;
                    app.SizeScaleEditField.Value = settings.sizeScale;
                    app.SizeScaleEditFieldValueChanged();
                    app.SimulationTimeEditField.Value = settings.simulationTime;
                    app.SimulationTimeEditFieldValueChanged();
                    app.TimeScaleEditField.Value = settings.timeScale;
                    app.TimeScaleEditFieldValueChanged();
                    app.TypeDropDown.Value = settings.type;
                    app.TypeDropDownValueChanged();
            
                    % 2.1 Mesh2DPanel
                    app.Mesh2DPanel.Visible = settings.isMesh2DPanel;
                    app.ShapeDropDown2D.Value = settings.shape2D;
                   
                    app.xlength2D.Value = settings.xlength2D;
                    app.xlength2DValueChanged();
                    app.ylength2D.Value = settings.ylength2D;
                    app.ylength2DValueChanged();
                    app.zlength2D.Value = settings.zlength2D;
                    app.zlength2DValueChanged();
                    app.NumberofNodes2Dx.Value = settings.NumberofNodes2Dx;
                    app.NumberofNodes2DxValueChanged();
                    app.NumberofNodes2Dy.Value = settings.NumberofNodes2Dy;
                    app.NumberofNodes2DyValueChanged();
            
                    % 2.2 Mesh3DPanel
                    app.Mesh3DPanel.Visible = settings.isMesh3DPanel;
                    app.ShapeDropDown3D.Value = settings.shape3D;
    
                    app.xlength3D.Value = settings.xlength3D;
                    app.xlength3DValueChanged();
                    app.ylength3D.Value = settings.ylength3D;
                    app.ylength3DValueChanged();
                    app.zlength3D.Value = settings.zlength3D;
                    app.zlength3DValueChanged();
    
                    app.NumberofNodes3Dx.Value = settings.NumberofNodes3Dx;
                    app.NumberofNodes3DxValueChanged();
                    app.NumberofNodes3Dy.Value = settings.NumberofNodes3Dy;
                    app.NumberofNodes3DyValueChanged();
                    app.NumberofNodes3Dz.Value = settings.NumberofNodes3Dz;
                    app.NumberofNodes3DzValueChanged();
            
                    % 3.1 Constrain2DPanel
                    app.Constrain2DPanel.Visible = settings.isConstrain2DPanel;
                    app.xyCon2DEditField.Value = settings.xyCon2D;
                    app.xCon2DEditField.Value = settings.xCon2D;
                    app.yCon2DEditField.Value = settings.yCon2D;
            
                    % 3.2 Constrain3DPanel
                    app.Constrain3DPanel.Visible = settings.isConstrain3DPanel;
                    app.xCon3DEditField.Value = settings.xCon3D;
                    app.yCon3DEditField.Value = settings.yCon3D;
                    app.zCon3DEditField.Value = settings.zCon3D;
                    app.xyCon3DEditField.Value = settings.xyCon3D;
                    app.xzCon3DEditField.Value = settings.xzCon3D;
                    app.yzCon3DEditField.Value = settings.yzCon3D;
                    app.xyzCon3DEditField.Value = settings.xyzCon3D;
            
                    % 4. MaterialAssignmentPanel
                    app.MaterialAssignmentPanel.Visible = settings.isMaterialAssignmentPanel;
                    app.NumberofsubregionsEditField.Value = settings.numSubregion;
                    app.SubregionProperties = settings.subregionProperties;
                    app.NumberofsubregionsEditFieldValueChanged(event);
                    if app.Mesh2DPanel.Visible
                        app.Mesh2DButtonPushed();
                    elseif app.Mesh3DPanel.Visible
                        app.Mesh3DButtonPushed();
                    end
                    if app.Constrain2DPanel.Visible
                        app.Apply2DButtonPushed();
                    elseif app.Constrain3DPanel.Visible
                        app.Apply3DButtonPushed();
                    end

                    app.SubregionApplyButtonPushed();

                    app.SavePathEditField.Value = settings.videoSaveFolder;
                    app.SavePathEditFieldValueChanged();                                   
                    % Notify the user that the settings were loaded successfully
                    uialert(app.UIFigure, 'Settings imported successfully.', 'Success');
                end
          catch ME
                msgbox(['Error occurred: ', ME.message], 'Error', 'error','modal');
                return;
          end
        end

        % Value changed function: AngleOutputDropDown
        function AngleOutputDropDownValueChanged(app, event)
            value = app.AngleOutputDropDown.Value;
            if strcmp(value, 'Def 1')
                app.isAngleOutput = 1;
            elseif strcmp(value, 'Def 2')
                app.isAngleOutput = 2;
            elseif strcmp(value, 'None')
                app.isAngleOutput =0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayoutOverview = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayoutOverview);
            app.GridLayout7 = uigridlayout(app.Panel);
            app.RunSimulationButton = uibutton(app.GridLayout7, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Icon = fullfile(pathToMLAPP, 'button.jpg');
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RunCostTextAreaLabel = uilabel(app.GridLayout7);
            app.RunCostTextAreaLabel.Text = 'Run Cost';
            app.RunCostTextArea = uitextarea(app.GridLayout7);
            app.SaveDataButton = uibutton(app.GridLayout7, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.GravityCheckBox = uicheckbox(app.GridLayout7);
            app.GravityCheckBox.ValueChangedFcn = createCallbackFcn(app, @GravityCheckBoxValueChanged, true);
            app.GravityCheckBox.Text = 'Gravity';
            app.GridLayoutTitle = uigridlayout(app.GridLayoutOverview);
            app.DPrintingLabel = uilabel(app.GridLayoutTitle);
            app.DPrintingLabel.Text = '4D Printing';
            app.ImportSettingsButton = uibutton(app.GridLayoutTitle, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Text = 'Import Settings';
            app.ExportSettingsButton = uibutton(app.GridLayoutTitle, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.VideoPanel = uipanel(app.GridLayoutOverview);
            app.VideoPanel.Title = 'Video';
            app.GridLayout5 = uigridlayout(app.VideoPanel);
            app.UIAxesVideo = uiaxes(app.GridLayout5);
            xlabel(app.UIAxesVideo, 'X')
            ylabel(app.UIAxesVideo, 'Y')
            zlabel(app.UIAxesVideo, 'Z')
            app.GenerateVideoButton = uibutton(app.GridLayout5, 'push');
            app.GenerateVideoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateVideoButtonPushed, true);
            app.GenerateVideoButton.Text = 'Generate Video';
            app.GridOnCheckBoxVideo = uicheckbox(app.GridLayout5);
            app.GridOnCheckBoxVideo.ValueChangedFcn = createCallbackFcn(app, @GridOnCheckBoxVideoValueChanged, true);
            app.GridOnCheckBoxVideo.Text = 'Grid On';
            app.BoxOnCheckBoxVideo = uicheckbox(app.GridLayout5);
            app.BoxOnCheckBoxVideo.ValueChangedFcn = createCallbackFcn(app, @BoxOnCheckBoxVideoValueChanged, true);
            app.BoxOnCheckBoxVideo.Text = 'Box On';
            app.SavePathButton = uibutton(app.GridLayout5, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = '...';
            app.PathLabel = uilabel(app.GridLayout5);
            app.PathLabel.Text = 'Path';
            app.SavePathEditField = uieditfield(app.GridLayout5, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.SliderLabel = uilabel(app.GridLayout5);
            app.SliderLabel.Interpreter = 'latex';
            app.SliderLabel.Text = '$1 \times$';
            app.Slider = uislider(app.GridLayout5);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 0.5;
            app.AngleOutputDropDownLabel = uilabel(app.GridLayout5);
            app.AngleOutputDropDownLabel.Text = 'Angle Output';
            app.AngleOutputDropDown = uidropdown(app.GridLayout5);
            app.AngleOutputDropDown.Items = {'Def 1', 'Def 2', 'None'};
            app.AngleOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @AngleOutputDropDownValueChanged, true);
            app.AngleOutputDropDown.Value = 'None';
            app.PreviewImagePanel = uipanel(app.GridLayoutOverview);
            app.PreviewImagePanel.Title = 'Preview Image';
            app.GridLayout4 = uigridlayout(app.PreviewImagePanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes, 'x [m]')
            ylabel(app.UIAxes, 'y [m]')
            zlabel(app.UIAxes, 'z [m]')
            app.GridOnCheckBox = uicheckbox(app.GridLayout4);
            app.GridOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridOnCheckBoxValueChanged, true);
            app.GridOnCheckBox.Text = 'Grid On';
            app.SaveAsButton = uibutton(app.GridLayout4, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.BoxOnCheckBox = uicheckbox(app.GridLayout4);
            app.BoxOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxOnCheckBoxValueChanged, true);
            app.BoxOnCheckBox.Text = 'Box On';
            app.MaterialAssignmentPanel = uipanel(app.GridLayoutOverview);
            app.MaterialAssignmentPanel.Title = 'Material Assignment';
            app.GridLayout2 = uigridlayout(app.MaterialAssignmentPanel);
            app.NumberofsubregionsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofsubregionsEditFieldLabel.Text = 'Number of subregions';
            app.NumberofsubregionsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofsubregionsEditField.Limits = [1 Inf];
            app.NumberofsubregionsEditField.RoundFractionalValues = 'on';
            app.NumberofsubregionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsubregionsEditFieldValueChanged, true);
            app.NumberofsubregionsEditField.Value = 1;
            app.SubregionDropDownLabel = uilabel(app.GridLayout2);
            app.SubregionDropDownLabel.Text = 'Subregion';
            app.SubregionDropDown = uidropdown(app.GridLayout2);
            app.SubregionDropDown.Items = {'1'};
            app.SubregionDropDown.ValueChangedFcn = createCallbackFcn(app, @SubregionDropDownValueChanged, true);
            app.SubregionDropDown.Value = '1';
            app.SubregionPanel = uipanel(app.GridLayout2);
            app.SubregionPanel.Title = 'Subregion 1';
            app.GridLayout3 = uigridlayout(app.SubregionPanel);
            app.YoungsModulusEditFieldLabel = uilabel(app.GridLayout3);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.YoungsModulusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YoungsModulusEditField.Limits = [0 Inf];
            app.YoungsModulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusEditFieldValueChanged, true);
            app.PaLabel = uilabel(app.GridLayout3);
            app.PaLabel.Text = 'Pa';
            app.DensityEditFieldLabel = uilabel(app.GridLayout3);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DensityEditField.Limits = [0 Inf];
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Value = 1000;
            app.ColorLabel = uilabel(app.GridLayout3);
            app.ColorLabel.Text = 'Color';
            app.ViscoCoefficientEditFieldLabel = uilabel(app.GridLayout3);
            app.ViscoCoefficientEditFieldLabel.Text = 'Visco Coefficient';
            app.ViscoCoefficientEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ViscoCoefficientEditField.Limits = [0 Inf];
            app.ViscoCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @ViscoCoefficientEditFieldValueChanged, true);
            app.ViscoCoefficientEditField.Value = 10;
            app.PoissonsRatioEditFieldLabel = uilabel(app.GridLayout3);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PoissonsRatioEditField.Limits = [-1 0.49999];
            app.PoissonsRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsRatioEditFieldValueChanged, true);
            app.PoissonsRatioEditField.Value = 0.45;
            app.SubregionApplyButton = uibutton(app.GridLayout3, 'push');
            app.SubregionApplyButton.ButtonPushedFcn = createCallbackFcn(app, @SubregionApplyButtonPushed, true);
            app.SubregionApplyButton.Text = 'Apply';
            app.MeasuredStrainLabel = uilabel(app.GridLayout3);
            app.MeasuredStrainLabel.Text = 'Measured Strain';
            app.kgm3Label = uilabel(app.GridLayout3);
            app.kgm3Label.Text = 'kg/m^3';
            app.REditFieldLabel = uilabel(app.GridLayout3);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.GridLayout3, 'numeric');
            app.REditField.Limits = [0 255];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.GridLayout3);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GEditField.Limits = [0 255];
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @GEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.GridLayout3);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BEditField.Limits = [0 255];
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.SetMsrXButton = uibutton(app.GridLayout3, 'push');
            app.SetMsrXButton.ButtonPushedFcn = createCallbackFcn(app, @SetMsrXButtonPushed, true);
            app.SetMsrXButton.Text = 'Set x';
            app.SetMsrYButton = uibutton(app.GridLayout3, 'push');
            app.SetMsrYButton.ButtonPushedFcn = createCallbackFcn(app, @SetMsrYButtonPushed, true);
            app.SetMsrYButton.Text = 'Set y';
            app.SetMsrZButton = uibutton(app.GridLayout3, 'push');
            app.SetMsrZButton.ButtonPushedFcn = createCallbackFcn(app, @SetMsrZButtonPushed, true);
            app.SetMsrZButton.Text = 'Set z';
            app.StrainZTextAreaLabel = uilabel(app.GridLayout3);
            app.StrainZTextAreaLabel.Interpreter = 'latex';
            app.StrainZTextAreaLabel.Text = '$w^{msr}_z(t)$';
            app.StrainYTextAreaLabel = uilabel(app.GridLayout3);
            app.StrainYTextAreaLabel.Interpreter = 'latex';
            app.StrainYTextAreaLabel.Text = '$v^{msr}_y(t)$';
            app.StrainXTextAreaLabel = uilabel(app.GridLayout3);
            app.StrainXTextAreaLabel.Interpreter = 'latex';
            app.StrainXTextAreaLabel.Text = '$u^{msr}_x(t)$';
            app.SetElementIndicesButton = uibutton(app.GridLayout3, 'push');
            app.SetElementIndicesButton.ButtonPushedFcn = createCallbackFcn(app, @SetElementIndicesButtonPushed, true);
            app.SetElementIndicesButton.Text = 'Set Element Indices';
            app.ElementIndicesLabel = uilabel(app.GridLayout3);
            app.ElementIndicesLabel.Text = 'Element Indices';
            app.Constrain3DPanel = uipanel(app.GridLayoutOverview);
            app.Constrain3DPanel.Title = 'Constrain 3D';
            app.GridLayout6 = uigridlayout(app.Constrain3DPanel);
            app.xfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.xfixedEditFieldLabel.Text = 'x-fixed';
            app.xCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.xyfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.xyfixedEditFieldLabel.Text = 'xy-fixed';
            app.xyCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.yfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.yfixedEditFieldLabel.Text = 'y-fixed';
            app.yCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.xzfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.xzfixedEditFieldLabel.Text = 'xz-fixed';
            app.xzCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.zfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.zfixedEditFieldLabel.Text = 'z-fixed';
            app.zCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.yzfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.yzfixedEditFieldLabel.Text = 'yz-fixed';
            app.yzCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.xyzfixedEditFieldLabel = uilabel(app.GridLayout6);
            app.xyzfixedEditFieldLabel.Text = 'xyz-fixed';
            app.xyzCon3DEditField = uieditfield(app.GridLayout6, 'text');
            app.Apply3DButton = uibutton(app.GridLayout6, 'push');
            app.Apply3DButton.ButtonPushedFcn = createCallbackFcn(app, @Apply3DButtonPushed, true);
            app.Apply3DButton.Text = 'Apply';
            app.Constrain2DPanel = uipanel(app.GridLayoutOverview);
            app.Constrain2DPanel.Title = 'Constrain 2D';
            app.GridLayout = uigridlayout(app.Constrain2DPanel);
            app.xdirectionLabel = uilabel(app.GridLayout);
            app.xdirectionLabel.Text = 'x-fixed';
            app.xCon2DEditField = uieditfield(app.GridLayout, 'text');
            app.ydirectionLabel = uilabel(app.GridLayout);
            app.ydirectionLabel.Text = 'y-fixed';
            app.yCon2DEditField = uieditfield(app.GridLayout, 'text');
            app.xyLabel = uilabel(app.GridLayout);
            app.xyLabel.Text = 'xy-fixed';
            app.xyCon2DEditField = uieditfield(app.GridLayout, 'text');
            app.Apply2DButton = uibutton(app.GridLayout, 'push');
            app.Apply2DButton.ButtonPushedFcn = createCallbackFcn(app, @Apply2DButtonPushed, true);
            app.Apply2DButton.Text = 'Apply';
            app.Mesh3DPanel = uipanel(app.GridLayoutOverview);
            app.Mesh3DPanel.Title = 'Mesh 3D';
            app.Mesh3DPanel.Visible = 'off';
            app.GridLayoutMesh3D = uigridlayout(app.Mesh3DPanel);
            app.ShapeDropDown_2Label = uilabel(app.GridLayoutMesh3D);
            app.ShapeDropDown_2Label.Text = 'Shape';
            app.ShapeDropDown3D = uidropdown(app.GridLayoutMesh3D);
            app.ShapeDropDown3D.Items = {'Cuboid'};
            app.ShapeDropDown3D.Value = 'Cuboid';
            app.xEditField_2Label = uilabel(app.GridLayoutMesh3D);
            app.xEditField_2Label.Text = 'x';
            app.xlength3D = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.xlength3D.Limits = [0 Inf];
            app.xlength3D.ValueChangedFcn = createCallbackFcn(app, @xlength3DValueChanged, true);
            app.mLabel_3 = uilabel(app.GridLayoutMesh3D);
            app.mLabel_3.Text = 'm';
            app.NumberofNodesEditFieldLabel = uilabel(app.GridLayoutMesh3D);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodes3Dx = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.NumberofNodes3Dx.Limits = [0 Inf];
            app.NumberofNodes3Dx.RoundFractionalValues = 'on';
            app.NumberofNodes3Dx.ValueChangedFcn = createCallbackFcn(app, @NumberofNodes3DxValueChanged, true);
            app.yEditField_2Label = uilabel(app.GridLayoutMesh3D);
            app.yEditField_2Label.Text = 'y';
            app.ylength3D = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.ylength3D.Limits = [0 Inf];
            app.ylength3D.ValueChangedFcn = createCallbackFcn(app, @ylength3DValueChanged, true);
            app.mLabel_4 = uilabel(app.GridLayoutMesh3D);
            app.mLabel_4.Text = 'm';
            app.NumberofNodesEditField_2Label = uilabel(app.GridLayoutMesh3D);
            app.NumberofNodesEditField_2Label.Text = 'Number of Nodes';
            app.NumberofNodes3Dy = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.NumberofNodes3Dy.Limits = [0 Inf];
            app.NumberofNodes3Dy.RoundFractionalValues = 'on';
            app.NumberofNodes3Dy.ValueChangedFcn = createCallbackFcn(app, @NumberofNodes3DyValueChanged, true);
            app.zEditFieldLabel = uilabel(app.GridLayoutMesh3D);
            app.zEditFieldLabel.Text = 'z';
            app.zlength3D = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.zlength3D.Limits = [0 Inf];
            app.zlength3D.ValueChangedFcn = createCallbackFcn(app, @zlength3DValueChanged, true);
            app.mLabel_5 = uilabel(app.GridLayoutMesh3D);
            app.mLabel_5.Text = 'm';
            app.NumberofNodesEditField_3Label = uilabel(app.GridLayoutMesh3D);
            app.NumberofNodesEditField_3Label.Text = 'Number of Nodes';
            app.NumberofNodes3Dz = uieditfield(app.GridLayoutMesh3D, 'numeric');
            app.NumberofNodes3Dz.Limits = [0 Inf];
            app.NumberofNodes3Dz.RoundFractionalValues = 'on';
            app.NumberofNodes3Dz.ValueChangedFcn = createCallbackFcn(app, @NumberofNodes3DzValueChanged, true);
            app.Mesh3DButton = uibutton(app.GridLayoutMesh3D, 'push');
            app.Mesh3DButton.ButtonPushedFcn = createCallbackFcn(app, @Mesh3DButtonPushed, true);
            app.Mesh3DButton.Text = 'Mesh3D';
            app.Mesh2DPanel = uipanel(app.GridLayoutOverview);
            app.Mesh2DPanel.Title = 'Mesh 2D';
            app.GridLayoutMesh2D = uigridlayout(app.Mesh2DPanel);
            app.ShapeDropDownLabel = uilabel(app.GridLayoutMesh2D);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.Mesh2DButton = uibutton(app.GridLayoutMesh2D, 'push');
            app.Mesh2DButton.ButtonPushedFcn = createCallbackFcn(app, @Mesh2DButtonPushed, true);
            app.Mesh2DButton.Text = 'Mesh2D';
            app.xlength2D = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.xlength2D.Limits = [0 Inf];
            app.xlength2D.ValueChangedFcn = createCallbackFcn(app, @xlength2DValueChanged, true);
            app.mLabel = uilabel(app.GridLayoutMesh2D);
            app.mLabel.Text = 'm';
            app.NumberofNodesLabel = uilabel(app.GridLayoutMesh2D);
            app.NumberofNodesLabel.Text = 'Number of Nodes';
            app.NumberofNodes2Dx = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.NumberofNodes2Dx.Limits = [0 Inf];
            app.NumberofNodes2Dx.RoundFractionalValues = 'on';
            app.NumberofNodes2Dx.ValueChangedFcn = createCallbackFcn(app, @NumberofNodes2DxValueChanged, true);
            app.ylength2D = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.ylength2D.Limits = [0 Inf];
            app.ylength2D.ValueChangedFcn = createCallbackFcn(app, @ylength2DValueChanged, true);
            app.mLabel_2 = uilabel(app.GridLayoutMesh2D);
            app.mLabel_2.Text = 'm';
            app.NumberofNodesLabel_2 = uilabel(app.GridLayoutMesh2D);
            app.NumberofNodesLabel_2.Text = 'Number of Nodes';
            app.NumberofNodes2Dy = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.NumberofNodes2Dy.Limits = [0 Inf];
            app.NumberofNodes2Dy.RoundFractionalValues = 'on';
            app.NumberofNodes2Dy.ValueChangedFcn = createCallbackFcn(app, @NumberofNodes2DyValueChanged, true);
            app.mLabel_6 = uilabel(app.GridLayoutMesh2D);
            app.mLabel_6.Text = 'm';
            app.zlength2D = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.zlength2D.ValueChangedFcn = createCallbackFcn(app, @zlength2DValueChanged, true);
            app.ShapeDropDown2D = uidropdown(app.GridLayoutMesh2D);
            app.ShapeDropDown2D.Items = {'Rectangular', 'Doughnut', 'Half Doughnut'};
            app.ShapeDropDown2D.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDown2DValueChanged, true);
            app.ShapeDropDown2D.Value = 'Rectangular';
            app.xLabel2D = uilabel(app.GridLayoutMesh2D);
            app.xLabel2D.Text = 'x';
            app.yLabel2D = uilabel(app.GridLayoutMesh2D);
            app.yLabel2D.Text = 'y';
            app.zLabel2D = uilabel(app.GridLayoutMesh2D);
            app.zLabel2D.Text = 'z';
            app.thetaLabel = uilabel(app.GridLayoutMesh2D);
            app.thetaLabel.Interpreter = 'latex';
            app.thetaLabel.Text = '\theta';
            app.theta = uieditfield(app.GridLayoutMesh2D, 'numeric');
            app.theta.ValueChangedFcn = createCallbackFcn(app, @thetaValueChanged, true);
            app.degLabel = uilabel(app.GridLayoutMesh2D);
            app.degLabel.Text = 'deg';
            app.SimulationSettingPanel = uipanel(app.GridLayoutOverview);
            app.SimulationSettingPanel.Title = 'Simulation Setting';
            app.GridLayoutSetting = uigridlayout(app.SimulationSettingPanel);
            app.SimulationTypeDropDownLabel = uilabel(app.GridLayoutSetting);
            app.SimulationTypeDropDownLabel.Text = 'Simulation Type';
            app.TypeDropDown = uidropdown(app.GridLayoutSetting);
            app.TypeDropDown.Items = {'2D', '3D'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '2D';
            app.TimeScaleEditFieldLabel = uilabel(app.GridLayoutSetting);
            app.TimeScaleEditFieldLabel.Text = 'Time Scale';
            app.TimeScaleEditField = uieditfield(app.GridLayoutSetting, 'numeric');
            app.TimeScaleEditField.Limits = [0 Inf];
            app.TimeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @TimeScaleEditFieldValueChanged, true);
            app.SimulationTimeEditFieldLabel = uilabel(app.GridLayoutSetting);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.GridLayoutSetting, 'numeric');
            app.SimulationTimeEditField.Limits = [0 Inf];
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SizeScaleEditFieldLabel = uilabel(app.GridLayoutSetting);
            app.SizeScaleEditFieldLabel.Text = 'Size Scale';
            app.SizeScaleEditField = uieditfield(app.GridLayoutSetting, 'numeric');
            app.SizeScaleEditField.Limits = [0 100];
            app.SizeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @SizeScaleEditFieldValueChanged, true);
            app.TimeUnitDropDown = uidropdown(app.GridLayoutSetting);
            app.TimeUnitDropDown.Items = {'s', 'min', 'h'};
            app.TimeUnitDropDown.Value = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFEM_4DP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end